classdef auxapp_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        Motor5EditField       matlab.ui.control.EditField
        Motor5EditFieldLabel  matlab.ui.control.Label
        Motor4EditField       matlab.ui.control.EditField
        Motor4EditFieldLabel  matlab.ui.control.Label
        Motor3EditField       matlab.ui.control.EditField
        Motor3EditFieldLabel  matlab.ui.control.Label
        Motor2EditField       matlab.ui.control.EditField
        Motor2EditFieldLabel  matlab.ui.control.Label
        Motor1EditField       matlab.ui.control.EditField
        Motor1EditFieldLabel  matlab.ui.control.Label
        ValoresarticularesrealesdecadamotorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
        q = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, auxapp)
            app.callingapp=auxapp;
            %sub = rossubscriber("/dynamixel_workbench/joint_states")
            %[data, status, statusText] = receive(sub, 2); 

            %q = data.Position; 

               
            % app.Motor1EditField.Value = num2str(q(1));
            % app.Motor2EditField.Value = num2str(q(2));
            % app.Motor3EditField.Value = num2str(q(3));
            % app.Motor4EditField.Value = num2str(q(4));
            % app.Motor5EditField.Value = num2str(q(5));

            app.Motor1EditField.Value = num2str(0.047241);
            app.Motor2EditField.Value = num2str(-0.007940);
            app.Motor3EditField.Value = num2str(0.006032);
            app.Motor4EditField.Value = num2str(-0.000103);
            app.Motor5EditField.Value = num2str(-0.001898);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValoresarticularesrealesdecadamotorLabel = uilabel(app.UIFigure);
            app.ValoresarticularesrealesdecadamotorLabel.Text = 'Valores articulares reales de cada motor';
            app.Motor1EditFieldLabel = uilabel(app.UIFigure);
            app.Motor1EditFieldLabel.Text = 'Motor 1';
            app.Motor1EditField = uieditfield(app.UIFigure, 'text');
            app.Motor2EditFieldLabel = uilabel(app.UIFigure);
            app.Motor2EditFieldLabel.Text = 'Motor 2';
            app.Motor2EditField = uieditfield(app.UIFigure, 'text');
            app.Motor3EditFieldLabel = uilabel(app.UIFigure);
            app.Motor3EditFieldLabel.Text = 'Motor 3';
            app.Motor3EditField = uieditfield(app.UIFigure, 'text');
            app.Motor4EditFieldLabel = uilabel(app.UIFigure);
            app.Motor4EditFieldLabel.Text = 'Motor 4';
            app.Motor4EditField = uieditfield(app.UIFigure, 'text');
            app.Motor5EditFieldLabel = uilabel(app.UIFigure);
            app.Motor5EditFieldLabel.Text = 'Motor 5';
            app.Motor5EditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rPose1.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auxapp_1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end