classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image2       matlab.ui.control.Image
        EscojacualquierposequedeseeprevisualizarparaluegoLabel  matlab.ui.control.Label
        Laboratorio4RobticadeDesarrolloCinemticaDirectaLabel  matlab.ui.control.Label
        AndresFelipeZuletaRomeroNelsonDavidRamrezMarnLabel  matlab.ui.control.Label
        Image        matlab.ui.control.Image
        Pose5Button  matlab.ui.control.Button
        Pose4Button  matlab.ui.control.Button
        Pose3Button  matlab.ui.control.Button
        Pose2Button  matlab.ui.control.Button
        Pose1Button  matlab.ui.control.Button
    end

    
    properties (Access = private)
        auxapp % Description
        auxapp2 % Description
        auxapp3 % Description
        auxapp4 % Description
        auxapp5 % Description % Description
    end
    
    methods (Access = public)
        
        function cambiarImagen(imagen, path)
            img = imread(path);
            imagen.ImageSource = img;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Pose1Button
        function Pose1ButtonPushed(app, event)
            app.auxapp=auxapp(app);
            app.Image2.ImageSource = imread('rPose1.jpeg');
            
        end

        % Button pushed function: Pose2Button
        function Pose2ButtonPushed(app, event)
            app.auxapp2=auxapp2(app);
            app.Image2.ImageSource = imread('rPose2.jpeg');
            
        end

        % Button pushed function: Pose3Button
        function Pose3ButtonPushed(app, event)
            app.auxapp3=auxapp3(app);
            app.Image2.ImageSource = imread('rPose3.jpeg');
            
        end

        % Button pushed function: Pose4Button
        function Pose4ButtonPushed(app, event)
            app.auxapp4=auxapp4(app);
            app.Image2.ImageSource = imread('rPose4.jpeg');
            
        end

        % Button pushed function: Pose5Button
        function Pose5ButtonPushed(app, event)
            app.auxapp5=auxapp5(app);
            app.Image2.ImageSource = imread('rPose5.jpeg');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Pose1Button = uibutton(app.UIFigure, 'push');
            app.Pose1Button.ButtonPushedFcn = createCallbackFcn(app, @Pose1ButtonPushed, true);
            app.Pose1Button.Tooltip = {'Ver Pose1'};
            app.Pose1Button.Text = 'Pose 1';
            app.Pose2Button = uibutton(app.UIFigure, 'push');
            app.Pose2Button.ButtonPushedFcn = createCallbackFcn(app, @Pose2ButtonPushed, true);
            app.Pose2Button.Tooltip = {'Ver Pose2'};
            app.Pose2Button.Text = 'Pose 2';
            app.Pose3Button = uibutton(app.UIFigure, 'push');
            app.Pose3Button.ButtonPushedFcn = createCallbackFcn(app, @Pose3ButtonPushed, true);
            app.Pose3Button.Tooltip = {'Ver Pose3'};
            app.Pose3Button.Text = 'Pose 3';
            app.Pose4Button = uibutton(app.UIFigure, 'push');
            app.Pose4Button.ButtonPushedFcn = createCallbackFcn(app, @Pose4ButtonPushed, true);
            app.Pose4Button.Tooltip = {'Ver Pose4'};
            app.Pose4Button.Text = 'Pose 4';
            app.Pose5Button = uibutton(app.UIFigure, 'push');
            app.Pose5Button.ButtonPushedFcn = createCallbackFcn(app, @Pose5ButtonPushed, true);
            app.Pose5Button.Tooltip = {'Ver Pose5'};
            app.Pose5Button.Text = 'Pose 5';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logotipo_de_la_Universidad_Nacional_de_Colombia.svg.png');
            app.AndresFelipeZuletaRomeroNelsonDavidRamrezMarnLabel = uilabel(app.UIFigure);
            app.AndresFelipeZuletaRomeroNelsonDavidRamrezMarnLabel.Text = {'Andres Felipe Zuleta Romero'; 'Nelson David Ramírez Marín'; 'Andres Mauricio Morales '};
            app.Laboratorio4RobticadeDesarrolloCinemticaDirectaLabel = uilabel(app.UIFigure);
            app.Laboratorio4RobticadeDesarrolloCinemticaDirectaLabel.Text = {'Laboratorio 4 - Robótica de Desarrollo '; 'Cinemática Directa'};
            app.EscojacualquierposequedeseeprevisualizarparaluegoLabel = uilabel(app.UIFigure);
            app.EscojacualquierposequedeseeprevisualizarparaluegoLabel.Text = 'Escoja cualquier pose que desee previsualizar para luego seleccionar si se desea enviarla al manipulador ';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'rPose1.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end